classdef VisTaira < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenlogMenu             matlab.ui.container.Menu
        SettingsMenu            matlab.ui.container.Menu
        RealTimemodeMenu        matlab.ui.container.Menu
        PostProcessingmodeMenu  matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        FlightDataTab_2         matlab.ui.container.Tab
        GridLayout7             matlab.ui.container.GridLayout
        ServoCommandsPanel      matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        TimeScope2_2            matlab.ui.scope.TimeScope
        TimeScope2              matlab.ui.scope.TimeScope
        IMUDataPanel            matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        TimeScope_5             matlab.ui.scope.TimeScope
        TimeScope_4             matlab.ui.scope.TimeScope
        EulerAnglesPanel        matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        TimeScope               matlab.ui.scope.TimeScope
        TimeScope_2             matlab.ui.scope.TimeScope
        TimeScope_3             matlab.ui.scope.TimeScope
        SimulationcontrolPanel  matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        TimeSlider              matlab.ui.control.Slider
        TimeSliderLabel         matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        ResumeButton            matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        AttitudePanel           matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        Altimeter               Aero.ui.control.Altimeter
        AltimeterLabel          matlab.ui.control.Label
        HeadingIndicator        Aero.ui.control.HeadingIndicator
        HeadingIndicatorLabel   matlab.ui.control.Label
        ArtificialHorizon       Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel  matlab.ui.control.Label
        CommandsTab             matlab.ui.container.Tab
    end

    
    properties (Access = private)
       % Animated lines for actual and target values
        pitchLine
        pitchTargetLine
        rollLine
        rollTargetLine
        yawLine
        yawTargetLine
        servoLine
        servoTargetLine
        
        t0         % Start time
        timerObj   % Timer for real-time updates % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenlogMenu
        function OpenlogMenuSelected(app, event)
            % Open file selection dialog
            [file, path] = uigetfile({'*.csv;*.txt;*.mat', 'Data Files (*.csv, *.txt, *.mat)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                      'Select Flight Data File');
            
            if isequal(file, 0)
                disp('File selection canceled');
                return;
            end
            
            fullFileName = fullfile(path, file);
            disp(['Loading file: ', fullFileName]);
    
            % Determine file type and read data
            [~, ~, ext] = fileparts(fullFileName);
            
            switch lower(ext)
                case '.csv'
                    data = readtable(fullFileName);
                case '.txt'
                    data = readtable(fullFileName, 'Delimiter', '\t'); % Modify delimiter if needed
                case '.mat'
                    loadedData = load(fullFileName);
                    data = struct2table(loadedData); % Convert struct to table if needed
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format', 'Error', 'Icon', 'error');
                    return;
            end
            % Assuming the file contains Time, Pitch, Roll, Yaw, Servo
            if all(ismember({'time', 'ax','ay','az', 'gx','gy','gz', 'rollTarget', 'pitchTarget', 'yawTarger', 'roll', 'pitch', 'yaw', 'rightServo', 'leftServo','elevator'}, data.Properties.VariableNames))
                % Clear previous plot
                clearpoints(app.pitchLine);
                clearpoints(app.rollLine);
                clearpoints(app.yawLine);
                clearpoints(app.servoLine);
            
                % Update plot with new data
                addpoints(app.pitchLine, data.Time, data.Pitch);
                addpoints(app.rollLine, data.Time, data.Roll);
                addpoints(app.yawLine, data.Time, data.Yaw);
                addpoints(app.servoLine, data.Time, data.Servo);
            
                % Adjust x-axis
                xlim(app.UIAxes, [min(data.Time), max(data.Time)]);
                drawnow;
            else
                uialert(app.UIFigure, 'Invalid file format. Ensure it contains Time, Pitch, Roll, Yaw, Servo.', 'Error', 'Icon', 'error');
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenlogMenu = uimenu(app.FileMenu);
            app.OpenlogMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenlogMenuSelected, true);
            app.OpenlogMenu.Text = 'Open log';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.RealTimemodeMenu = uimenu(app.SettingsMenu);
            app.RealTimemodeMenu.Text = 'Real Time mode';
            app.PostProcessingmodeMenu = uimenu(app.SettingsMenu);
            app.PostProcessingmodeMenu.Text = 'Post Processing mode';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FlightDataTab_2 = uitab(app.TabGroup);
            app.FlightDataTab_2.Title = 'Flight Data';
            app.GridLayout7 = uigridlayout(app.FlightDataTab_2);
            app.AttitudePanel = uipanel(app.GridLayout7);
            app.AttitudePanel.Title = 'Attitude';
            app.GridLayout = uigridlayout(app.AttitudePanel);
            app.ArtificialHorizonLabel = uilabel(app.GridLayout);
            app.ArtificialHorizonLabel.Text = 'Artificial Horizon';
            app.ArtificialHorizon = uiaerohorizon(app.GridLayout);
            app.ArtificialHorizon.Pitch = 10;
            app.ArtificialHorizon.Roll = 10;
            app.HeadingIndicatorLabel = uilabel(app.GridLayout);
            app.HeadingIndicatorLabel.Text = 'Heading Indicator';
            app.HeadingIndicator = uiaeroheading(app.GridLayout);
            app.AltimeterLabel = uilabel(app.GridLayout);
            app.AltimeterLabel.Text = 'Altimeter';
            app.Altimeter = uiaeroaltimeter(app.GridLayout);
            app.SimulationcontrolPanel = uipanel(app.GridLayout7);
            app.SimulationcontrolPanel.Title = 'Simulation control';
            app.GridLayout3 = uigridlayout(app.SimulationcontrolPanel);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.Text = 'Start';
            app.ResumeButton = uibutton(app.GridLayout3, 'push');
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.Text = 'Stop';
            app.TimeSliderLabel = uilabel(app.GridLayout3);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.GridLayout3);
            app.EulerAnglesPanel = uipanel(app.GridLayout7);
            app.EulerAnglesPanel.Title = 'Euler Angles';
            app.GridLayout4 = uigridlayout(app.EulerAnglesPanel);
            app.TimeScope_3 = uitimescope(app.GridLayout4);
            app.TimeScope_3.Title = 'Yaw';
            app.TimeScope_3.YLabel = 'Amplitude';
            app.TimeScope_2 = uitimescope(app.GridLayout4);
            app.TimeScope_2.Title = 'Roll';
            app.TimeScope_2.YLabel = 'Amplitude';
            app.TimeScope = uitimescope(app.GridLayout4);
            app.TimeScope.Title = 'Pitch';
            app.TimeScope.YLabel = 'Amplitude';
            app.IMUDataPanel = uipanel(app.GridLayout7);
            app.IMUDataPanel.Title = 'IMU Data';
            app.GridLayout5 = uigridlayout(app.IMUDataPanel);
            app.TimeScope_4 = uitimescope(app.GridLayout5);
            app.TimeScope_4.Title = 'Accelerations';
            app.TimeScope_4.YLabel = 'Amplitude';
            app.TimeScope_5 = uitimescope(app.GridLayout5);
            app.TimeScope_5.Title = 'Angular Rates';
            app.TimeScope_5.YLabel = 'Amplitude';
            app.ServoCommandsPanel = uipanel(app.GridLayout7);
            app.ServoCommandsPanel.Title = 'Servo Commands';
            app.GridLayout6 = uigridlayout(app.ServoCommandsPanel);
            app.TimeScope2 = uitimescope(app.GridLayout6);
            app.TimeScope2.Title = 'Left and Right servo';
            app.TimeScope2.YLabel = 'Amplitude';
            app.TimeScope2_2 = uitimescope(app.GridLayout6);
            app.TimeScope2_2.Title = 'Elevator';
            app.TimeScope2_2.YLabel = 'Amplitude';
            app.CommandsTab = uitab(app.TabGroup);
            app.CommandsTab.Title = 'Commands';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisTaira

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end